classdef GRAPH_PLOTTING < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        AMPLITUDEFREQUENCYFORMTab  matlab.ui.container.Tab
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        EditField_9                matlab.ui.control.NumericEditField
        EditField_8                matlab.ui.control.NumericEditField
        EditField_7                matlab.ui.control.NumericEditField
        EditField_6                matlab.ui.control.NumericEditField
        EditField_5                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.NumericEditField
        FREQUENCYLabel             matlab.ui.control.Label
        PHASELabel                 matlab.ui.control.Label
        AMPLITUDELabel             matlab.ui.control.Label
        SYNTHESIZEButton           matlab.ui.control.Button
        PLOTButton                 matlab.ui.control.Button
        SINECOSINEFORMTab          matlab.ui.container.Tab
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        SYNTHESIZEButton_2         matlab.ui.control.Button
        PLOTButton_2               matlab.ui.control.Button
        EditField_24               matlab.ui.control.NumericEditField
        EditField_23               matlab.ui.control.NumericEditField
        EditField_22               matlab.ui.control.NumericEditField
        EditField_20               matlab.ui.control.NumericEditField
        EditField_19               matlab.ui.control.NumericEditField
        EditField_17               matlab.ui.control.NumericEditField
        EditField_16               matlab.ui.control.NumericEditField
        EditField_14               matlab.ui.control.NumericEditField
        EditField_13               matlab.ui.control.NumericEditField
        EditField_12               matlab.ui.control.NumericEditField
        EditField_11               matlab.ui.control.NumericEditField
        EditField_10               matlab.ui.control.NumericEditField
        FrequencywLabel            matlab.ui.control.Label
        a0Label                    matlab.ui.control.Label
        bkLabel                    matlab.ui.control.Label
        akLabel                    matlab.ui.control.Label
        GRAPHPLOTTINGLabel         matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
             %the x axis
             t = linspace(0, 1, 1000);
            
             % getting the values of the first graph
            A1 = app.EditField.Value;
            f1 = app.EditField_2.Value;
            theta1 = app.EditField_3.Value;
            
            % getting the values of the second graph
            A2 = app.EditField_4.Value;
            f2 = app.EditField_5.Value;
            theta2 = app.EditField_6.Value;
    
            % getting the values of the third graph
            A3 = app.EditField_7.Value;
            f3 = app.EditField_8.Value;
            theta3 = app.EditField_9.Value;
    
            % calculating the cosines
            y_cos1 = cosine(A1, f1, theta1);
            y_cos2 = cosine(A2, f2, theta2);
            y_cos3 = cosine(A3, f3, theta3);

            % calculating the sines
            y_sin1 = sine(A1, f1, theta1);
            y_sin2 = sine(A2, f2, theta2);
            y_sin3 = sine(A3, f3, theta3);
    
            % decide which graphs to print on the screen, sines or cosines
            str = app.Switch.Value;
            if(strcmp(str, 'COSINE') == 1)
          
                %plotting the cosines
                plot(t, y_cos1, 'b', 'Parent', app.UIAxes);
                hold(app.UIAxes, 'on');
                plot(t, y_cos2, 'r', 'Parent', app.UIAxes);
                hold(app.UIAxes, 'on');
                plot(t, y_cos3, 'g', 'Parent', app.UIAxes);
                hold(app.UIAxes, 'off');
            else
          
                %plotting the sines
                plot(t, y_sin1, 'b', 'Parent', app.UIAxes);
                hold(app.UIAxes, 'on');
                plot(t, y_sin2, 'r', 'Parent', app.UIAxes);
                hold(app.UIAxes, 'on');
                plot(t, y_sin3, 'g', 'Parent', app.UIAxes);
                hold(app.UIAxes, 'off');
            end

        end

        % Button pushed function: SYNTHESIZEButton
        function SYNTHESIZEButtonPushed(app, event)

            %the x axis
             t = linspace(0, 1, 1000);
            
             % getting the values of the first graph
            A1 = app.EditField.Value;
            f1 = app.EditField_2.Value;
            theta1 = app.EditField_3.Value;
            
            % getting the values of the second graph
            A2 = app.EditField_4.Value;
            f2 = app.EditField_5.Value;
            theta2 = app.EditField_6.Value;
    
            % getting the values of the third graph
            A3 = app.EditField_7.Value;
            f3 = app.EditField_8.Value;
            theta3 = app.EditField_9.Value;
    
            % calculating the cosines
            y_cos1 = cosine(A1, f1, theta1);
            y_cos2 = cosine(A2, f2, theta2);
            y_cos3 = cosine(A3, f3, theta3);

            % calculating the sines
            y_sin1 = sine(A1, f1, theta1);
            y_sin2 = sine(A2, f2, theta2);
            y_sin3 = sine(A3, f3, theta3);

            sinth = (y_sin1 + y_sin2 + y_sin3) + (y_cos1 + y_cos2 + y_cos3);
            
            plot(sinth, 'Parent', app.UIAxes2);
        end

        % Button pushed function: PLOTButton_2
        function PLOTButtonPushed2(app, event)
            t = linspace(0, 1, 1000);

            ak = zeros(5);
            bk = zeros(5);

            ak(1) = app.EditField_10.Value;
            ak(2) = app.EditField_13.Value;
            ak(3) = app.EditField_16.Value;
            ak(4) = app.EditField_19.Value;
            ak(5) = app.EditField_22.Value;

            bk(1) = app.EditField_11.Value;
            bk(2) = app.EditField_14.Value;
            bk(3) = app.EditField_17.Value;
            bk(4) = app.EditField_20.Value;
            bk(5) = app.EditField_23.Value;

            a0 = app.EditField_12.Value;
            w0 = app.EditField_24.Value;
            
            [y1, y2, y3, y4, y5, main_y] = sine_cosine_form(a0, ak, bk, w0);

            plot(t, y1, 'b', 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(t, y2, 'r', 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(t, y3, 'g', 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(t, y4, 'y', 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(t, y5, 'k', 'Parent', app.UIAxes);
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: SYNTHESIZEButton_2
        function SYNTHESIZEButtonPushed2(app, event)
            t = linspace(0, 1, 1000);

            ak = zeros(5);
            bk = zeros(5);

            ak(1) = app.EditField_10.Value;
            ak(2) = app.EditField_13.Value;
            ak(3) = app.EditField_16.Value;
            ak(4) = app.EditField_19.Value;
            ak(5) = app.EditField_22.Value

            bk(1) = app.EditField_11.Value;
            bk(2) = app.EditField_14.Value;
            bk(3) = app.EditField_17.Value;
            bk(4) = app.EditField_20.Value;
            bk(5) = app.EditField_23.Value;

            a0 = app.EditField_12.Value;
            w0 = app.EditField_24.Value;
            
            [y1, y2, y3, y4, y5, main_y] = sine_cosine_form(a0, ak, bk, w0);
            plot(t, main_y, 'Parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GRAPHS')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'SYNTHESIS')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'y')
            zlabel(app.UIAxes2, 'Z')
            app.GRAPHPLOTTINGLabel = uilabel(app.UIFigure);
            app.GRAPHPLOTTINGLabel.Text = 'GRAPH PLOTTING';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AMPLITUDEFREQUENCYFORMTab = uitab(app.TabGroup);
            app.AMPLITUDEFREQUENCYFORMTab.Title = 'AMPLITUDE-FREQUENCY FORM';
            app.PLOTButton = uibutton(app.AMPLITUDEFREQUENCYFORMTab, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.SYNTHESIZEButton = uibutton(app.AMPLITUDEFREQUENCYFORMTab, 'push');
            app.SYNTHESIZEButton.ButtonPushedFcn = createCallbackFcn(app, @SYNTHESIZEButtonPushed, true);
            app.SYNTHESIZEButton.Text = {'SYNTHESIZE'; ''};
            app.AMPLITUDELabel = uilabel(app.AMPLITUDEFREQUENCYFORMTab);
            app.AMPLITUDELabel.Text = 'AMPLITUDE';
            app.PHASELabel = uilabel(app.AMPLITUDEFREQUENCYFORMTab);
            app.PHASELabel.Text = 'PHASE';
            app.FREQUENCYLabel = uilabel(app.AMPLITUDEFREQUENCYFORMTab);
            app.FREQUENCYLabel.Text = 'FREQUENCY';
            app.EditField = uieditfield(app.AMPLITUDEFREQUENCYFORMTab, 'numeric');
            app.EditField_2 = uieditfield(app.AMPLITUDEFREQUENCYFORMTab, 'numeric');
            app.EditField_3 = uieditfield(app.AMPLITUDEFREQUENCYFORMTab, 'numeric');
            app.EditField_4 = uieditfield(app.AMPLITUDEFREQUENCYFORMTab, 'numeric');
            app.EditField_5 = uieditfield(app.AMPLITUDEFREQUENCYFORMTab, 'numeric');
            app.EditField_6 = uieditfield(app.AMPLITUDEFREQUENCYFORMTab, 'numeric');
            app.EditField_7 = uieditfield(app.AMPLITUDEFREQUENCYFORMTab, 'numeric');
            app.EditField_8 = uieditfield(app.AMPLITUDEFREQUENCYFORMTab, 'numeric');
            app.EditField_9 = uieditfield(app.AMPLITUDEFREQUENCYFORMTab, 'numeric');
            app.Switch = uiswitch(app.AMPLITUDEFREQUENCYFORMTab, 'slider');
            app.Switch.Items = {'COSINE', 'SINE'};
            app.Switch.Value = 'COSINE';
            app.Label = uilabel(app.AMPLITUDEFREQUENCYFORMTab);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.AMPLITUDEFREQUENCYFORMTab);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.AMPLITUDEFREQUENCYFORMTab);
            app.Label_3.Text = '3';
            app.SINECOSINEFORMTab = uitab(app.TabGroup);
            app.SINECOSINEFORMTab.Title = 'SINE-COSINE FORM';
            app.akLabel = uilabel(app.SINECOSINEFORMTab);
            app.akLabel.Text = 'a(k)';
            app.bkLabel = uilabel(app.SINECOSINEFORMTab);
            app.bkLabel.Text = 'b(k)';
            app.a0Label = uilabel(app.SINECOSINEFORMTab);
            app.a0Label.Text = 'a(0)';
            app.FrequencywLabel = uilabel(app.SINECOSINEFORMTab);
            app.FrequencywLabel.Text = 'Frequency (w)';
            app.EditField_10 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_11 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_12 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_13 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_14 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_16 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_17 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_19 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_20 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_22 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_23 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.EditField_24 = uieditfield(app.SINECOSINEFORMTab, 'numeric');
            app.PLOTButton_2 = uibutton(app.SINECOSINEFORMTab, 'push');
            app.PLOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed2, true);
            app.PLOTButton_2.Text = 'PLOT';
            app.SYNTHESIZEButton_2 = uibutton(app.SINECOSINEFORMTab, 'push');
            app.SYNTHESIZEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SYNTHESIZEButtonPushed2, true);
            app.SYNTHESIZEButton_2.Text = 'SYNTHESIZE';
            app.Label_4 = uilabel(app.SINECOSINEFORMTab);
            app.Label_4.Text = '1';
            app.Label_5 = uilabel(app.SINECOSINEFORMTab);
            app.Label_5.Text = '2';
            app.Label_6 = uilabel(app.SINECOSINEFORMTab);
            app.Label_6.Text = '3';
            app.Label_7 = uilabel(app.SINECOSINEFORMTab);
            app.Label_7.Text = '4';
            app.Label_8 = uilabel(app.SINECOSINEFORMTab);
            app.Label_8.Text = '5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GRAPH_PLOTTING

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end